classdef emg_rc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EMGDisplayRCUIFigure           matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        TabControls                    matlab.ui.container.Tab
        CheckBoxDisplayMEP             matlab.ui.control.CheckBox
        CheckBoxRecruitCurve           matlab.ui.control.CheckBox
        FilenameprefixEditFieldLabel   matlab.ui.control.Label
        EditFieldFilenameprefix        matlab.ui.control.EditField
        CheckBoxSavedata               matlab.ui.control.CheckBox
        SaveLocationEditFieldLabel     matlab.ui.control.Label
        SaveLocationEditField          matlab.ui.control.EditField
        SelectButton                   matlab.ui.control.Button
        CheckBoxSici                   matlab.ui.control.CheckBox
        SaveFileName                   matlab.ui.control.Label
        CheckBoxAverageEmg             matlab.ui.control.CheckBox
        CheckBox_channel1              matlab.ui.control.CheckBox
        CheckBox_channel2              matlab.ui.control.CheckBox
        CheckBox_channel3              matlab.ui.control.CheckBox
        CheckBox_channel4              matlab.ui.control.CheckBox
        TabParameters                  matlab.ui.container.Tab
        preEmgMinEditField             matlab.ui.control.NumericEditField
        PreEMGRangeLabel               matlab.ui.control.Label
        preEmgMaxEditField             matlab.ui.control.NumericEditField
        msLabel                        matlab.ui.control.Label
        EMGPeaktoPeakValueButtonGroup  matlab.ui.container.ButtonGroup
        UseMEPAbsoluteValueButton      matlab.ui.control.RadioButton
        SubtractPreEMGppButton         matlab.ui.control.RadioButton
        MEPThresholdEditFieldLabel     matlab.ui.control.Label
        MEPThresholdEditField          matlab.ui.control.NumericEditField
        VLabel                         matlab.ui.control.Label
        PreEMGValueButtonGroup         matlab.ui.container.ButtonGroup
        MeanRectifiedValueButton       matlab.ui.control.RadioButton
        MaxPeaktoPeakButton            matlab.ui.control.RadioButton
        ParameterFileLabel             matlab.ui.control.Label
        FixedinParameterFilePanel      matlab.ui.container.Panel
        EMGSamplingFreqLabel           matlab.ui.control.Label
        ActivationGoalLabel            matlab.ui.control.Label
        DisplaySaveEMGLabel            matlab.ui.control.Label
        PreTriggerLabel                matlab.ui.control.Label
        PostTriggerLabel               matlab.ui.control.Label
    end


    properties (Access = public)
        params           % parameters of the emg data (sampFreq, pre & postTriggerTime)
        
        emg_data_fig     % figure to display an emg epoch
        h_disp_emg_axes  % axes to display emg data epoch
        
        emg_data_mmap       % memory map to emg data from emg_activity_mvc_goal.mlapp
        data_channels_mmap  % memory map with list of all muscle channels sent from emg_activity_mvc_goal.mlapp
        
        h_emg_line       % handle to emg data line
        h_t_min_line     % handle to vertical line at lower time for finding MEP
        h_t_max_line     % handle to vertical line at upper time for finding MEP
        mep_value_text   % uicontrol to display mep amplitude
        pre_emg_text     % uicontrol to display pre-stim emg
        xmin_edit        % uicontrol to display axes xmin
        xmax_edit        % uicontrol to display axes xmax
        sample_num_text  % uicontrol to display the saved sample number
        active_sample_checkbox      % uicontrol to indicate if a emg data sample is to be used (for averaging)
        next_sample_pushbutton      % uicontrol to go to next sample
        previous_sample_pushbutton  % uicontrol to go to previous sample
        
        fname_sample_struct % struct to store number of samples in each file created
                            % filename (without .txt) is a field of the struct, the
                            % value is the number of samples in the file
        fullfilename        % full path & file where data is being written
        
       %rc_data_mmap    % memory map of data for recruitment_curve.mlapp
        rc_fig          % recruit curve figure
        rc_axes         % axes in the recruit curve fig
        rc_fit_ui       % struct with uicontrols for rc fit
        rc_fit_info     % struct with curve fit info
        
        sici_fig        % sici & icf figure
        sici_popmenu    % popup menu in sici figure listing the type of stim (test, sici, icf)
        sici_axes       % axes on sici figure
        sici_ui         % struct with uicontrols for sici
        sici_info       % struct with sici info
        
        avg_emg_fig     % average emg data figure
        avg_emg_axes    % axes in the avg emg fig
        avg_num_lines   % uicontrol with number of lines being averaged
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            request_param_fname = true;
            if nargin > 1
                param_fname = varargin{1};
                if exist(param_fname, 'file')
                    param_fname = which(param_fname);
                    request_param_fname = false;  
                end
            end
            if request_param_fname % request parameter file
                disp('Select parameter file')
                [fname, pname] = uigetfile('*.txt', 'Select parameter file');
                param_fname = fullfile(pname, fname);
            end

            app.ParameterFileLabel.Text = ['Parameter File: ' param_fname];
            get_emg_rc_parameters(app, param_fname)
            setup_memmap(app, 'emg_data')
            setup_memmap(app, 'data_channels');
            app.EditFieldFilenameprefix.Value = ['x' datestr(now,'yyyymmdd_')];
            % save the channel number in the CheckBox_channelX userdata 
            app.CheckBox_channel1.UserData = 1;
            app.CheckBox_channel2.UserData = 2;
            app.CheckBox_channel3.UserData = 3;
            app.CheckBox_channel4.UserData = 4;
        end

        % Value changed function: CheckBoxDisplayMEP
        function disp_mep_checkbox(app, event)
            if app.CheckBoxDisplayMEP.Value
                display_mep(app, 'start')
            else
                display_mep(app, 'stop')
            end
        end

        % Value changed function: CheckBoxRecruitCurve
        function rc_checkbox(app, event)
            if app.CheckBoxRecruitCurve.Value
                display_rc(app, 'start')
            else
                display_rc(app, 'stop')
            end
        end

        % Callback function
        function magspy_checkbox(app, event)
            if app.CheckBoxMagSpy.Value
%                 [status, result] = system( 'matlab -r magspy &');
            else
            end
            
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            save_loc = uigetdir('/data/tms_recruitment_curve','Pick save location');
            app.SaveLocationEditField.Value = save_loc;
        end

        % Value changed function: CheckBoxSici
        function sici_checkbox(app, event)
            if app.CheckBoxSici.Value
                display_sici(app, 'start')
            else
                display_sici(app, 'stop')
            end
        end

        % Value changed function: EditFieldFilenameprefix
        function EditFieldFilenameprefixValueChanged(app, event)
            value = app.EditFieldFilenameprefix.Value;
            % verify that the value begins with a letter so valid struct
            % fields are created in app.fname_sample_struct
            if ~isempty(value) && ~isletter(value(1))
                app.EditFieldFilenameprefix.Value = ['x' app.EditFieldFilenameprefix.Value];
            end
        end

        % Value changed function: CheckBoxAverageEmg
        function CheckBoxAverageEmgValueChanged(app, event)
            if app.CheckBoxAverageEmg.Value
                display_average_emg(app, 'start')
            else
                display_average_emg(app, 'stop')
            end
        end

        % Value changed function: CheckBox_channel1, 
        % CheckBox_channel2, CheckBox_channel3, CheckBox_channel4
        function CheckBox_channelX_ValueChanged(app, event)
            channel_number = event.Source.UserData;
            app.data_channels_mmap.Data(channel_number).save = uint8(event.Value);
        end

        % Value changed function: CheckBoxSavedata
        function CheckBoxSavedataValueChanged(app, event)
            if app.CheckBoxSavedata.Value  % saving data
                %  find the live_display channel and check its checkbox
                ch_struc = app.data_channels_mmap.Data;
                for c_cnt = 1:ch_struc(1).num_channels
                    if ch_struc(c_cnt).live_display
                        chkbox_var = ['CheckBox_channel' num2str(c_cnt)];
                        app.(chkbox_var).Value = 1;
                    end
                end
            else
                % uncheck all channels
                for c_cnt = 1:4
                    chkbox_var = ['CheckBox_channel' num2str(c_cnt)];
                    app.(chkbox_var).Value = 0;
                end
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EMGDisplayRCUIFigure = uifigure;
            app.EMGDisplayRCUIFigure.Name = 'EMG Display & RC';
            app.TabGroup = uitabgroup(app.EMGDisplayRCUIFigure);
            app.TabControls = uitab(app.TabGroup);
            app.TabControls.Title = 'Controls';
            app.CheckBoxDisplayMEP = uicheckbox(app.TabControls);
            app.CheckBoxDisplayMEP.ValueChangedFcn = createCallbackFcn(app, @disp_mep_checkbox, true);
            app.CheckBoxDisplayMEP.Text = 'Display MEP';
            app.CheckBoxRecruitCurve = uicheckbox(app.TabControls);
            app.CheckBoxRecruitCurve.ValueChangedFcn = createCallbackFcn(app, @rc_checkbox, true);
            app.CheckBoxRecruitCurve.Text = 'Recruitment Curve';
            app.FilenameprefixEditFieldLabel = uilabel(app.TabControls);
            app.FilenameprefixEditFieldLabel.Text = 'File name prefix';
            app.EditFieldFilenameprefix = uieditfield(app.TabControls, 'text');
            app.EditFieldFilenameprefix.ValueChangedFcn = createCallbackFcn(app, @EditFieldFilenameprefixValueChanged, true);
            app.CheckBoxSavedata = uicheckbox(app.TabControls);
            app.CheckBoxSavedata.ValueChangedFcn = createCallbackFcn(app, @CheckBoxSavedataValueChanged, true);
            app.CheckBoxSavedata.Text = 'Save data';
            app.SaveLocationEditFieldLabel = uilabel(app.TabControls);
            app.SaveLocationEditFieldLabel.Text = 'Save Location';
            app.SaveLocationEditField = uieditfield(app.TabControls, 'text');
            app.SelectButton = uibutton(app.TabControls, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.CheckBoxSici = uicheckbox(app.TabControls);
            app.CheckBoxSici.ValueChangedFcn = createCallbackFcn(app, @sici_checkbox, true);
            app.CheckBoxSici.Text = 'SICI & ICF';
            app.SaveFileName = uilabel(app.TabControls);
            app.SaveFileName.Text = 'no file';
            app.CheckBoxAverageEmg = uicheckbox(app.TabControls);
            app.CheckBoxAverageEmg.ValueChangedFcn = createCallbackFcn(app, @CheckBoxAverageEmgValueChanged, true);
            app.CheckBoxAverageEmg.Text = 'Average EMG';
            app.CheckBox_channel1 = uicheckbox(app.TabControls);
            app.CheckBox_channel1.ValueChangedFcn = createCallbackFcn(app, @CheckBox_channelX_ValueChanged, true);
            app.CheckBox_channel2 = uicheckbox(app.TabControls);
            app.CheckBox_channel2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_channelX_ValueChanged, true);
            app.CheckBox_channel2.Visible = 'off';
            app.CheckBox_channel3 = uicheckbox(app.TabControls);
            app.CheckBox_channel3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_channelX_ValueChanged, true);
            app.CheckBox_channel3.Visible = 'off';
            app.CheckBox_channel4 = uicheckbox(app.TabControls);
            app.CheckBox_channel4.ValueChangedFcn = createCallbackFcn(app, @CheckBox_channelX_ValueChanged, true);
            app.CheckBox_channel4.Visible = 'off';
            app.TabParameters = uitab(app.TabGroup);
            app.TabParameters.Title = 'Parameters';
            app.preEmgMinEditField = uieditfield(app.TabParameters, 'numeric');
            app.preEmgMinEditField.Value = -101;
            app.PreEMGRangeLabel = uilabel(app.TabParameters);
            app.PreEMGRangeLabel.Text = 'Pre EMG Range';
            app.preEmgMaxEditField = uieditfield(app.TabParameters, 'numeric');
            app.preEmgMaxEditField.Value = -1;
            app.msLabel = uilabel(app.TabParameters);
            app.msLabel.Text = 'ms';
            app.EMGPeaktoPeakValueButtonGroup = uibuttongroup(app.TabParameters);
            app.EMGPeaktoPeakValueButtonGroup.Title = 'EMG Peak-to-Peak Value';
            app.UseMEPAbsoluteValueButton = uiradiobutton(app.EMGPeaktoPeakValueButtonGroup);
            app.UseMEPAbsoluteValueButton.Text = 'Use MEP Absolute Value';
            app.UseMEPAbsoluteValueButton.Value = true;
            app.SubtractPreEMGppButton = uiradiobutton(app.EMGPeaktoPeakValueButtonGroup);
            app.SubtractPreEMGppButton.Text = 'Subtract Pre EMG p-p';
            app.MEPThresholdEditFieldLabel = uilabel(app.TabParameters);
            app.MEPThresholdEditFieldLabel.Text = 'MEP Threshold';
            app.MEPThresholdEditField = uieditfield(app.TabParameters, 'numeric');
            app.MEPThresholdEditField.Value = 200;
            app.VLabel = uilabel(app.TabParameters);
            app.VLabel.Text = 'µV';
            app.PreEMGValueButtonGroup = uibuttongroup(app.TabParameters);
            app.PreEMGValueButtonGroup.Title = 'Pre EMG Value';
            app.MeanRectifiedValueButton = uiradiobutton(app.PreEMGValueButtonGroup);
            app.MeanRectifiedValueButton.Text = 'Mean Rectified Value';
            app.MeanRectifiedValueButton.Value = true;
            app.MaxPeaktoPeakButton = uiradiobutton(app.PreEMGValueButtonGroup);
            app.MaxPeaktoPeakButton.Text = 'Max Peak-to-Peak';
            app.ParameterFileLabel = uilabel(app.TabParameters);
            app.ParameterFileLabel.Text = 'Parameter File:';
            app.FixedinParameterFilePanel = uipanel(app.TabParameters);
            app.FixedinParameterFilePanel.Title = 'Fixed in Parameter File';
            app.EMGSamplingFreqLabel = uilabel(app.FixedinParameterFilePanel);
            app.EMGSamplingFreqLabel.Text = 'EMG Sampling Freq: xxxx Hz';
            app.ActivationGoalLabel = uilabel(app.FixedinParameterFilePanel);
            app.ActivationGoalLabel.Text = 'Activation Goal: xx% mvc';
            app.DisplaySaveEMGLabel = uilabel(app.FixedinParameterFilePanel);
            app.DisplaySaveEMGLabel.Text = 'Display & Save EMG:';
            app.PreTriggerLabel = uilabel(app.FixedinParameterFilePanel);
            app.PreTriggerLabel.Text = 'Pre Trigger: xxx ms';
            app.PostTriggerLabel = uilabel(app.FixedinParameterFilePanel);
            app.PostTriggerLabel.Text = 'Post Trigger: xxx ms';
        end
    end

    methods (Access = public)

        % Construct app
        function app = emg_rc(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EMGDisplayRCUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EMGDisplayRCUIFigure)
        end
    end
end