classdef emg_trace_viewer < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		EMG_Trace_Viewer_Fig       matlab.ui.Figure
		GridLayout                 matlab.ui.container.GridLayout
		LeftPanel                  matlab.ui.container.Panel
		TMSAnalysisHierarchyLabel  matlab.ui.control.Label
		SubjectTree                matlab.ui.container.Tree
		RightPanel                 matlab.ui.container.Panel
	end

	% Properties that correspond to apps with auto-reflow
	properties (Access = private)
		onePanelWidth = 576;
	end


	properties (Access = public)
		expected_sessions        % list of the expected session names (different for tdcs vs myopro)
		expected_analysis_files  % list of the expected analysis file names (muscles) (different for tdcs vs myopro)
	end


	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			% look for analysis info files
			analysis_dir = pwd;
			if ~contains(analysis_dir, [filesep 'tms'], "IgnoreCase", true) || ...
					~contains(analysis_dir, [filesep 'analysis' filesep], "IgnoreCase", true)
				uialert(app.EMG_Trace_Viewer_Fig, {'Current folder is not within an Analysis hierchy.' ...
					'In the dialog box, choose an Analysis/TMS folder.'}, 'Analysis Folder', 'Icon', 'warning')
				analysis_dir = uigetdir(pwd, 'Select TMS analysis folder');
				if analysis_dir==0
					uialert(app.TMSInfotoDatabaseUIFigure, 'No folder was selected.', 'No selection', 'Icon', 'warning')
					return
				end
			end
			% list of all emg trace files
			file_list = regexpdir(analysis_dir,'(.*epoch.*\.png)$');
			if length(file_list) < 1
				msg_txt = {'Found no *epoch*.png files.' ...
					'Select the TMS analysis folder with subject codes as folders.' ...
					'In the dialog box, choose an Analysis/TMS folder.'};
				uialert(app.EMG_Trace_Viewer_Fig, msg_txt, 'Files not found', 'Icon', 'warning')

				analysis_dir = uigetdir(pwd, 'Select TMS analysis folder');
				if analysis_dir==0
					uialert(app.EMG_Trace_Viewer_Fig, 'No folder was selected.', 'No selection', 'Icon', 'warning')
					return
				end
				file_list = regexpdir(analysis_dir,'(.*epoch.*\.png)$');
				if length(file_list) < 1
					msg_txt = {'Found no *epoch*.png files.' };
					uialert(app.EMG_Trace_Viewer_Fig, msg_txt, 'Files not found', 'Icon', 'warning')
					return
				end
			end

			% determine if this is gait or myomo
			if contains(lower(analysis_dir), 'tdcs')
				app.expected_sessions = {'pre' 'mid' 'post' 'followup'};
				app.expected_analysis_files = {'uninv_gastroc_rc' 'inv_gastroc_rc' 'uninv_ta_rc' 'inv_ta_rc' 'uninv_ta_sici' 'inv_ta_sici'};
			elseif contains(lower(analysis_dir), 'myopro')
				app.expected_sessions = {'week01' 'week04' 'week09' 'week18' 'week24'};
				app.expected_analysis_files = {'uninv_biceps_rc' 'inv_biceps_rc' 'uninv_triceps_rc' 'inv_triceps_rc' 'uninv_biceps_sici' 'inv_biceps_sici'};
			else
				error('do not know what sessions and muscles to use from this path: %s', analysis_dir)
			end

			original_file_list = file_list; % save to reuse the list later

			% parse through the file list for subject, session, muscle -
			% remove files from the list as they are added to the SubjectTree
			while ~isempty(file_list)
				% parse the filename
				[pathname, ~, ~] = fileparts(file_list{1});

				% get the subject name format: [s|c][####][xxxx]
				subj_str = regexp(pathname, '([sc][\d]+\w+)', 'match');
				subj_str = subj_str{1};
				uitreenode(app.SubjectTree, 'Text', subj_str);

				% all this subject's files
				f_ind = contains(file_list, subj_str);

				% remove this subject files from the list
				file_list(f_ind) = [];
			end

			% for file, parse the filename for subject, session, muscle and add to tree
			for f_cnt = 1:length(file_list)
				% parse the filename
				[pathname, filename, ~] = fileparts(file_list{1});
				% get the subject name format: [s|c][####][xxxx]
				subj_str = regexp(pathname, '([sc][\d]+\w+)', 'match');
				subj_str = subj_str{1};
				% muscle format: [inv|uninv]_[gastroc|ta|biceps|triceps]_[rc|sici]
				muscle_str = regexp(filename, '[inv|uninv]_[gastroc|ta|biceps|triceps]_[rc|sici]', 'match');
				muscle_str = muscle_str{1};
				% session
				session_str = regexp(pathname, '[pre|mid|post|followup|week01|')

				% is this subject in the tree?
					% no, add the subject, muscle, session, epoch
					% 
				subj_str = app.SubjectTree.Children(s_cnt).Text;
				for m_cnt = 1:length(app.expected_analysis_files)
					muscle = app.expected_analysis_files{m_cnt};
					% find the subject and muscle in the original_file_list
					match_pattern = sprintf('.*%s.*%s.*', subj_str, muscle);
					match_cell = regexp(original_file_list, match_pattern, 'match');
					% check each match_cell
					for mc_cnt = 1:length(match_cell)
						if ~isempty(match_cell{mc_cnt})
							% found a match
							file_cell = match_cell{mc_cnt};

							% is this muscle in the tree?
								% no, add the muscle, session, epoch
								% yes, is this session in the tree?
									% no, add the session, epoch
									% yes, is this epoch in the tree
										% no, add the epoch
										% yes, warn about a duplicate


						end
					end % through each possible matching cell
				end % for loop through each muscle

			end % for loop through each subject
		end

		% Changes arrangement of the app based on UIFigure width
		function updateAppLayout(app, event)
			currentFigureWidth = app.EMG_Trace_Viewer_Fig.Position(3);
			if(currentFigureWidth <= app.onePanelWidth)
			    % Change to a 2x1 grid
			    app.GridLayout.RowHeight = {480, 480};
			    app.GridLayout.ColumnWidth = {'1x'};
			    app.RightPanel.Layout.Row = 2;
			    app.RightPanel.Layout.Column = 1;
			else
			    % Change to a 1x2 grid
			    app.GridLayout.RowHeight = {'1x'};
			    app.GridLayout.ColumnWidth = {220, '1x'};
			    app.RightPanel.Layout.Row = 1;
			    app.RightPanel.Layout.Column = 2;
			end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.EMG_Trace_Viewer_Fig = uifigure('Visible', 'off');
			app.EMG_Trace_Viewer_Fig.Name = 'EMG Trace Viewer';
			app.EMG_Trace_Viewer_Fig.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
			app.GridLayout = uigridlayout(app.EMG_Trace_Viewer_Fig);
			app.LeftPanel = uipanel(app.GridLayout);
			app.SubjectTree = uitree(app.LeftPanel);
			app.SubjectTree.Multiselect = 'on';
			app.TMSAnalysisHierarchyLabel = uilabel(app.LeftPanel);
			app.TMSAnalysisHierarchyLabel.Text = 'TMS Analysis Hierarchy';
			app.RightPanel = uipanel(app.GridLayout);
			% Show the figure after all components are created
			app.EMG_Trace_Viewer_Fig.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = emg_trace_viewer

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.EMG_Trace_Viewer_Fig)

				% Execute the startup function
				runStartupFcn(app, @startupFcn)
			else

				% Focus the running singleton app
				figure(runningApp.EMG_Trace_Viewer_Fig)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.EMG_Trace_Viewer_Fig)
		end
	end
end