classdef info_to_db < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		UIFigure     matlab.ui.Figure
		GridLayout   matlab.ui.container.GridLayout
		LeftPanel    matlab.ui.container.Panel
		SubjectTree  matlab.ui.container.Tree
		Node         matlab.ui.container.TreeNode
		Node2        matlab.ui.container.TreeNode
		Node3        matlab.ui.container.TreeNode
		Node4        matlab.ui.container.TreeNode
		RightPanel   matlab.ui.container.Panel
	end

	% Properties that correspond to apps with auto-reflow
	properties (Access = private)
		onePanelWidth = 576;
	end

	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			% look for analysis info files
			analysis_dir = pwd;
			if ~contains(analysis_dir, [filesep 'tms'], "IgnoreCase", true) || ...
					~contains(analysis_dir, [filesep 'analysis' filesep], "IgnoreCase", true)
				analysis_dir = uigetdir(pwd, 'Select TMS analysis folder');
				if analysis_dir==0
					disp('No folder selected')
					return
				end
			end
			% list of all files with info to go to the database
			file_list = regexpdir(analysis_dir,'(.*datapoints_analysis_info\.txt)$');
			if length(file_list) < 1
				disp('found no *datapoints_analysis_info.txt files.')
				disp('Select the TMS analysis folder with subject codes as folders.')

				analysis_dir = uigetdir(pwd, 'Select TMS analysis folder');
				if analysis_dir==0
					disp('No folder selected')
					return
				end
				file_list = regexpdir(analysis_dir,'(.*datapoints_analysis_info\.txt)$');
				if length(file_list) < 1
					disp('found no *datapoints_analysis_info.txt files.')
					return
				end
			end

			% determine if this is gait or myomo
			if contains(lower(analysis_dir), 'tdcs')
				db_str = 'tdcs_vgait';
			elseif contains(lower(analysis_dir), 'myomo')
				db_str = 'myomo';
			else
				error('do not know what database to use from this path: %s', analysis_dir)
			end

			% parse through the file list for subject, session, muscle -
			% remove files from the list as they are added to the Subject
			% Tree
			while ~isempty(file_list)
				% parse the filename
				[pathname, filename, ~] = fileparts(file_list{1});

				% get the subject name format: [s|c][####][xxxx]
				subj_str = regexp(pathname, '([sc][\d]+\w+)', 'match');
				subj_str = subj_str{1};
				uitreenode(app.SubjectTree, 'Text', subj_str);

				% all this subject's files
				f_ind = find(contains(file_list, subj_str));

				% remove this subject files from the list
				file_list(f_ind) = []; 

			end

keyboard
			% read the subject folders
		end

		% Changes arrangement of the app based on UIFigure width
		function updateAppLayout(app, event)
			currentFigureWidth = app.UIFigure.Position(3);
			if(currentFigureWidth <= app.onePanelWidth)
			    % Change to a 2x1 grid
			    app.GridLayout.RowHeight = {480, 480};
			    app.GridLayout.ColumnWidth = {'1x'};
			    app.RightPanel.Layout.Row = 2;
			    app.RightPanel.Layout.Column = 1;
			else
			    % Change to a 1x2 grid
			    app.GridLayout.RowHeight = {'1x'};
			    app.GridLayout.ColumnWidth = {306, '1x'};
			    app.RightPanel.Layout.Row = 1;
			    app.RightPanel.Layout.Column = 2;
			end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.UIFigure = uifigure('Visible', 'off');
			app.UIFigure.Name = 'MATLAB App';
			app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
			app.GridLayout = uigridlayout(app.UIFigure);
			app.LeftPanel = uipanel(app.GridLayout);
			app.SubjectTree = uitree(app.LeftPanel);
			app.Node = uitreenode(app.SubjectTree);
			app.Node.Text = 'Node';
			app.Node2 = uitreenode(app.Node);
			app.Node2.Text = 'Node2';
			app.Node3 = uitreenode(app.Node);
			app.Node3.Text = 'Node3';
			app.Node4 = uitreenode(app.Node);
			app.Node4.Text = 'Node4';
			app.RightPanel = uipanel(app.GridLayout);
			% Show the figure after all components are created
			app.UIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = info_to_db

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.UIFigure)

				% Execute the startup function
				runStartupFcn(app, @startupFcn)
			else

				% Focus the running singleton app
				figure(runningApp.UIFigure)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.UIFigure)
		end
	end
end